classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ColorPicker        matlab.ui.control.ColorPicker
        Label              matlab.ui.control.Label
        cEditField         matlab.ui.control.NumericEditField
        cEditFieldLabel    matlab.ui.control.Label
        bEditField_2       matlab.ui.control.NumericEditField
        bEditField_2Label  matlab.ui.control.Label
        aEditField         matlab.ui.control.NumericEditField
        aEditFieldLabel    matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            color = app.ColorPicker.Value;
            a = app.aEditField.Value;
            b = app.bEditField_2.Value;
            c = app.cEditField.Value;
            x = -10:0.001:10;
            ax = app.UIAxes;
            ax.XTick = -10:10;
            p = plot(ax, x, a*x.^2+b*x+c);
            p.Color = color;
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            color = app.ColorPicker.Value;
            a = app.aEditField.Value;
            b = app.bEditField_2.Value;
            c = app.cEditField.Value;
            x = -10:0.001:10;
            ax = app.UIAxes;
            p = plot(ax, x, a*x.^2+b*x+c);
            p.Color = color;
        end

        % Value changed function: bEditField_2
        function bEditField_2ValueChanged(app, event)
            color = app.ColorPicker.Value;
            a = app.aEditField.Value;
            b = app.bEditField_2.Value;
            c = app.cEditField.Value;
            x = -10:0.001:10;
            ax = app.UIAxes;
            p = plot(ax, x, a*x.^2+b*x+c);
            p.Color = color;
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            color = app.ColorPicker.Value;
            a = app.aEditField.Value;
            b = app.bEditField_2.Value;
            c = app.cEditField.Value;
            x = -10:0.001:10;
            ax = app.UIAxes;
            p = plot(ax, x, a*x.^2+b*x+c);
            p.Color = color;
        end

        % Callback function: ColorPicker
        function ColorPickerValueChanged(app, event)
            color = app.ColorPicker.Value;
            a = app.aEditField.Value;
            b = app.bEditField_2.Value;
            c = app.cEditField.Value;
            x = -10:0.001:10;
            ax = app.UIAxes;
            p = plot(ax, x, a*x.^2+b*x+c);
            p.Color = color;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'y=ax^{2}+bx+c')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a值';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditField_2Label = uilabel(app.UIFigure);
            app.bEditField_2Label.Text = 'b值';
            app.bEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.bEditField_2.ValueChangedFcn = createCallbackFcn(app, @bEditField_2ValueChanged, true);
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c值';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '线段颜色';
            app.ColorPicker = uicolorpicker(app.UIFigure);
            app.ColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorPickerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end